classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        shuttingdownButton  matlab.ui.control.Button
        ButtonGroup         matlab.ui.container.ButtonGroup
        Button3             matlab.ui.control.RadioButton
        Button2             matlab.ui.control.RadioButton
        Button_2            matlab.ui.control.RadioButton
        Button              matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        comport % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.comport = serialport('/dev/tty.usbmodem103',115200);
            % Send 'U' to trigger sampling
            writeline(app.comport, "U");
            while(1) %synchronization
                % Read 400 bytes (8-bit ADC values)
                data = read(app.comport, 400, "uint8");
                % Convert to voltage (V)
                V_ref = 3.3; % ADC reference voltage (Vcc ~= 3.3V) calibrated
                voltage = (data / 255) * V_ref; % Convert to volts 2^8 instead of 2^10
                % Plot data try plotting data instead of Voltage
                ax=app.UIAxes;
                plot(ax, voltage, 'LineWidth', 1.5);
                xlabel(ax, 'Sample Index');
                ylabel(ax, 'Voltage (V)');
                title(ax, 'ADC Samples');
                grid(ax, 'on');
                drawnow; % Force GUI update
            end
        end

        % Button pushed function: shuttingdownButton
        function shuttingdownButtonPushed(app, event)
            clear("app.comport")
            close
            delete (app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.shuttingdownButton = uibutton(app.UIFigure, 'push');
            app.shuttingdownButton.ButtonPushedFcn = createCallbackFcn(app, @shuttingdownButtonPushed, true);
            app.shuttingdownButton.Text = 'shutting down';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end